classdef yFoil < matlab.apps.AppBase
    %YFOIL App that generates approximated equations of NACA Airfoils for
    %use in Autodesk Inventor
    %   A NACA airfoil is a series of standardised 
    %   airfoil (or wing cross-sections), developed by the agency now
    %   known as NASA. The parameters inside a 4-digit NACA code can be
    %   used 
    %   to parametrically define the equation for the cross section.
    %   
    %   However, the formula for a NACA airfoil is very complicated, and 
    %   cannot be imported easily into CAD software like inventor.
    %   
    %   Therefore, this app generates approximations of the preferred
    %   naca airfoil for use in inventor

    % Properties that correspond to app components
    properties (Access = public)
        yFoilUIFigure                matlab.ui.Figure
        RootGridLayout               matlab.ui.container.GridLayout
        ReferenceProfileTabGroup     matlab.ui.container.TabGroup
        NACAProfileTab               matlab.ui.container.Tab
        NacaGridLayout_2             matlab.ui.container.GridLayout
        NacaPropertiesGridLayout     matlab.ui.container.GridLayout
        ThicknessSpinner             matlab.ui.control.Spinner
        ThicknessSpinnerLabel        matlab.ui.control.Label
        ChamberPosSpinner            matlab.ui.control.Spinner
        ChamberPosLabel              matlab.ui.control.Label
        ChamberSpinner               matlab.ui.control.Spinner
        ChamberSpinnerLabel          matlab.ui.control.Label
        PointsLabel                  matlab.ui.control.Label
        NacaQualitySpinner           matlab.ui.control.Spinner
        QualitySpinnerLabel          matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        QueryField                   matlab.ui.control.EditField
        NACACodeLabel                matlab.ui.control.Label
        ImportPointsTab              matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        PickFileButton               matlab.ui.control.Button
        ImportedPointsStatsLabel     matlab.ui.control.Label
        LogoImage                    matlab.ui.control.Image
        FooterGridLayout             matlab.ui.container.GridLayout
        PerformanceLabel             matlab.ui.control.Label
        PinWindowCheckBox            matlab.ui.control.CheckBox
        HelpHyperlink                matlab.ui.control.Hyperlink
        YFoilProfileOptionsPanel     matlab.ui.container.Panel
        YFoilGridLayout              matlab.ui.container.GridLayout
        YFoilLowerOutputLabel        matlab.ui.control.Label
        YFoilUpperOutputLabel        matlab.ui.control.Label
        LowerSurfaceQualitySpinner   matlab.ui.control.Spinner
        LowerSurfaceQualityLabel     matlab.ui.control.Label
        ChordParameterNameEditField  matlab.ui.control.EditField
        ChordParameterNameLabel      matlab.ui.control.Label
        UpperSurfaceQualitySpinner   matlab.ui.control.Spinner
        UpperSurfaceQualityLabel     matlab.ui.control.Label
        GeneratedEquationsPanel      matlab.ui.container.Panel
        EquationsGridLayout          matlab.ui.container.GridLayout
        lyEquationCopyButton         matlab.ui.control.Button
        lyEquationLabel              matlab.ui.control.Label
        lxEquationCopyButton         matlab.ui.control.Button
        lxEquationLabel              matlab.ui.control.Label
        uyEquationCopyButton         matlab.ui.control.Button
        uyEquationLabel              matlab.ui.control.Label
        uxEquationCopyButton         matlab.ui.control.Button
        uxEquationLabel              matlab.ui.control.Label
        Axes                         matlab.ui.control.UIAxes
    end

 
    properties (Access = private)
        Naca % Current NacaProfile
        Import {mustBeA(Import, "ImportProfile")} = ImportProfile.empty(); % Current Imported Profile
        CurrentProfile {mustBeA(CurrentProfile, "Profile")} = Profile.empty;
        YFoil {mustBeA(YFoil, "YProfile")} = YProfile.empty() % Current YProfile
        ReferenceComputeTime = 0 % Time taken to compute the reference profile
        YFoilComputeTime = 0 % Time taken to compute the yFoil profile
    end
    
    methods (Access = private)

        function ApplyNewNaca(app, newNaca)
            %APPLYNEWNACA Applies the given NacaProfile object to the app
            % and updates any dependents

            % Save the naca object
            app.Naca = newNaca;
            % Update the spinners
            app.ChamberSpinner.Value = app.Naca.M;
            app.ChamberPosSpinner.Value = app.Naca.P;
            app.ThicknessSpinner.Value = app.Naca.T;

            % Update dependent yFoil
            app.CurrentProfile = app.Naca;
            app.UpdateYf();
        end
        
        function ApplyNewImportProfile(app, newImportProfile, deferUpdate)
            %APPLYNEWIPORTPROFILE Applies the given Import profile object
            %to the app and updates any dependents
            arguments
                app
                newImportProfile
                deferUpdate = false
            end

            % Save the object
            app.Import = newImportProfile;

            % Update status
            app.ImportedPointsStatsLabel.Text = app.Import.GetStats();

            % Update dependent yFoil
            if (~deferUpdate)
                app.CurrentProfile = app.Import;
                app.UpdateYf();
            end
        end

        function UpdateNacaSurface(app)
            %UPDATENACASURFACE Recomputes the NacaProfile surfaces
            % and updates any dependents.
            % This function is called when any of the key properties (M, P
            % or T) or the quality changes, but not an edit to the actual
            % query field itself.

            % Set the query field to current properties
            app.QueryField.Value = app.Naca.GetDigits();
            % Recompute surface 
            tic();
            app.Naca.ComputeSurface(app.GetQuality());
            app.ReferenceComputeTime = toc();

            % Update dependents
            app.UpdateYf();
        end
        
        function UpdateYf(app)
            %UPDATEYF Regenerate the yFoil profile and update dependents
            
            % Recompute yFoil
            tic();
            qualityVector = [app.UpperSurfaceQualitySpinner.Value, app.LowerSurfaceQualitySpinner.Value];

            app.YFoil = YProfile(app.CurrentProfile, qualityVector);

            app.YFoilComputeTime = toc();

            % Update dependents
            app.UpdateEquationLabels();  
            app.UpdatePerformanceLabel(); 
            app.UpdateAxes();
        end

        function UpdatePerformanceLabel(app)
            %UPDATEPERFORMANCELABEL Updates the performance label with the
            %elapsed computation times for generating the profiles if not
            %cleared to zero.
            output = '';
            if (app.ReferenceComputeTime ~= 0)
                output = sprintf("Computing reference: %.3fms.", app.ReferenceComputeTime * 1000);
                app.ReferenceComputeTime = 0;
            end
            if (app.YFoilComputeTime ~= 0)
                output = sprintf("%s Computing yFoil: %.3fms.", output, app.YFoilComputeTime * 1000);
                app.YFoilComputeTime = 0;
            end
            app.PerformanceLabel.Text = output;
        end

        function UpdateAxes(app)
            %UPDATEAXES Redraw the preview plot

            % Clear previous content in the axes, if needed
            cla(app.Axes);
            % Set x-axes limit
            xlim(app.Axes,[-0.2 1.2]);
            % Set y-axis limit
            ylim(app.Axes,[-ceil((app.Naca.T / 2 + 0.05) * 10) / 10, ceil((app.Naca.M + app.Naca.T / 2 + 0.05) * 10) / 10]);

            hold(app.Axes,"on");

            % Plot elements onto the graph:

            % Plot the chamber line of the reference profile onto the axes
            if (~isempty(app.CurrentProfile.ChamberLine))
                plot(app.Axes,app.CurrentProfile.ChamberLine(1,:), app.CurrentProfile.ChamberLine(2,:), ...
                    Color='g', DisplayName='Chamber Line');
            end
            % Plot the upper surface of the reference profile onto the axes
            plot(app.Axes,app.CurrentProfile.UpperSurface(1,:), app.CurrentProfile.UpperSurface(2,:), ...
                'mx', DisplayName='Upper Surface Points');
            % Plot the lower surface of the reference profile onto the axes
            plot(app.Axes,app.CurrentProfile.LowerSurface(1,:), app.CurrentProfile.LowerSurface(2,:), ...
                'mx', DisplayName='Lower Surface Points');
            
            % Plot the yFoil Upper and Lower surfaces
            plot(app.Axes,app.YFoil.UpperSurface(1,:), app.YFoil.UpperSurface(2,:), ...
                Color=[0 0.2 1], DisplayName='yFoil Upper Surface');
            plot(app.Axes,app.YFoil.LowerSurface(1,:), app.YFoil.LowerSurface(2,:), ...
                Color=[0.2 0 1], DisplayName='yFoil Lower Surface');

            hold(app.Axes,"off");
        end
        
        function UpdateEquationLabels(app)
            %UPDATEEQUATIONLABELS Update the equation labels and some other
            %outputs

            % Print the number of NACA points generated
            app.PointsLabel.Text = sprintf('%d pts', size(app.Naca.UpperSurface,2) + size(app.Naca.LowerSurface,2));

            % Print the degree and quality of the yFoil equations generated
            app.YFoilUpperOutputLabel.Text = ...
                sprintf("n = %d, r² = %g", ...
                length(app.YFoil.UpperCoefficientsVector), app.YFoil.UpperR2);
            app.YFoilLowerOutputLabel.Text = ...
                sprintf("n = %d, r² = %g", ...
                length(app.YFoil.LowerCoefficientsVector), app.YFoil.LowerR2);

            % Update all four equation labels
            app.uxEquationLabel.Text = app.YFoil.GetEquation('ux', app.ChordParameterNameEditField.Value);
            app.uyEquationLabel.Text = app.YFoil.GetEquation('uy', app.ChordParameterNameEditField.Value);
            app.lxEquationLabel.Text = app.YFoil.GetEquation('lx', app.ChordParameterNameEditField.Value);
            app.lyEquationLabel.Text = app.YFoil.GetEquation('ly', app.ChordParameterNameEditField.Value);
        end
        
        function xPositionVector = GetQuality(app)
            %GETQUALITY Gets a vector with values from 0 to 1 for use as
            %the x-position of the points for the NACA surface.

            xPositionVector = (0:0.4/2^(app.NacaQualitySpinner.Value):1).^3;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            % Ensure that the plot scale is square (so that there's no distortion)
            daspect(app.Axes, [1 1 1]);
            
            % Apply and compute a default NACA and Import profile, 
            % and update dependents
            tic();
            app.ApplyNewNaca(NacaProfile.GenerateFromDigits(app.QueryField.Value, app.GetQuality()));
            app.ApplyNewImportProfile(ImportProfile("GOE 567.txt"), true);
            app.ReferenceComputeTime = toc();
            
            % Add a Legend to the axes
            legend(app.Axes);
        end

        % Button pushed function: lxEquationCopyButton, 
        % ...and 3 other components
        function CopyButtonPushed(app, event)
            % This function is called by any of the four equation copy
            % buttons. Each button has it's tag property set to the name of
            % the equation ('ux', 'uy' ...). The GetEquation method is used
            % to generate the equation
            clipboard("copy", app.YFoil.GetEquation(char(event.Source.Tag), app.ChordParameterNameEditField.Value));            
        end

        % Value changing function: QueryField
        function QueryFieldValueChanging(app, event)
            % Called when the 'NACA Code:' field is edited, setting the
            % lamp next to it to grey to indicate that query has not been
            % computed yet
            app.Lamp.Color = [0.5,0.5,0.5];
        end

        % Value changed function: QueryField
        function QueryFieldValueChanged(app, event)
            % Called when edits to the 'NACA Code:' field are commited 
            % (say, by pressing enter). This starts the update process
            tic();
            % Try create a new naca profile
            newNaca = NacaProfile.GenerateFromDigits(app.QueryField.Value, ...
                app.GetQuality());
            app.ReferenceComputeTime = toc();

            % If the query is invalid, GenerateFromDigits returns an empty.            
            if (~isempty(newNaca)) % If not, apply the new profile
                app.Lamp.Color = [0,1,0];
                app.ApplyNewNaca(newNaca);
            else % Turn lamp red
                app.Lamp.Color = [1,0,0];
            end
        end

        % Value changed function: ChordParameterNameEditField
        function ChordParameterNameEditFieldValueChanged(app, event)
            % Updates equations (which are dependent on chord parameter)
            app.UpdateEquationLabels();
        end

        % Value changed function: NacaQualitySpinner
        function NacaQualitySpinnerValueChanged(app, event)
            % Recomputes the naca surface when different quality (number of
            % reference points0 is needed
            app.UpdateNacaSurface();
        end

        % Value changed function: LowerSurfaceQualitySpinner, 
        % ...and 1 other component
        function SurfaceQualitySpinnerValueChanged(app, event)
            % Recomputes the yfoil if a different quality (r2 value) is
            % requested
            app.UpdateYf();
        end

        % Value changed function: ChamberSpinner
        function ChamberSpinnerValueChanged(app, event)
            % Updates the chamber (M) and recomputes
            app.Naca.M = app.ChamberSpinner.Value;
            app.UpdateNacaSurface();
        end

        % Value changed function: ChamberPosSpinner
        function ChamberPosSpinnerValueChanged(app, event)
            % Updates the chamber position (P) and recomputes
            app.Naca.P = app.ChamberPosSpinner.Value;
            app.UpdateNacaSurface();
        end

        % Value changed function: ThicknessSpinner
        function ThicknessSpinnerValueChanged(app, event)
            % Updates the thickness (T) and recomputes
            app.Naca.T = app.ThicknessSpinner.Value;
            app.UpdateNacaSurface();
        end

        % Value changed function: PinWindowCheckBox
        function PinWindowCheckBoxValueChanged(app, event)
            % Pins the window to the top of all other windows when checked
            if (app.PinWindowCheckBox.Value)
                app.yFoilUIFigure.WindowStyle = "alwaysontop";
            else
                app.yFoilUIFigure.WindowStyle = "normal";
            end
        end

        % Button pushed function: PickFileButton
        function PickFileButtonPushed(app, event)
            % Pick a file
            [file, path] = uigetfile({'*.txt;*.dat';'*.*'}, 'Select Lednicer Airfoil Data file');
            tic();
            newImport = ImportProfile(fullfile(path,file));
            app.ReferenceComputeTime = toc();
            app.ApplyNewImportProfile(newImport);
        end

        % Selection change function: ReferenceProfileTabGroup
        function ReferenceProfileTabGroupSelectionChanged(app, event)
            selectedTab = app.ReferenceProfileTabGroup.SelectedTab;
            if (selectedTab.Tag == "Naca")
                app.CurrentProfile = app.Naca;
            else
                app.CurrentProfile = app.Import;
            end
            app.UpdateYf();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.yFoilUIFigure = uifigure('Visible', 'off');
            app.yFoilUIFigure.Name = 'yFoil 1.1';
            app.yFoilUIFigure.Icon = fullfile(pathToMLAPP, 'Logo.png');
            app.RootGridLayout = uigridlayout(app.yFoilUIFigure);
            app.Axes = uiaxes(app.RootGridLayout);
            title(app.Axes, 'Plot of YFoil (Blue) and Reference Profile (Magenta)')
            xlabel(app.Axes, 'Chord')
            ylabel(app.Axes, 'Y')
            zlabel(app.Axes, 'Z')
            app.Axes.XAxisLocation = 'top';
            app.Axes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Axes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.GeneratedEquationsPanel = uipanel(app.RootGridLayout);
            app.GeneratedEquationsPanel.Title = 'Generated Equations';
            app.EquationsGridLayout = uigridlayout(app.GeneratedEquationsPanel);
            app.uxEquationLabel = uilabel(app.EquationsGridLayout);
            app.uxEquationLabel.Tooltip = {'Upper surface x equation'};
            app.uxEquationCopyButton = uibutton(app.EquationsGridLayout, 'push');
            app.uxEquationCopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.uxEquationCopyButton.Tag = 'ux';
            app.uxEquationCopyButton.Tooltip = {'Copy equation to clipboard'};
            app.uxEquationCopyButton.Text = 'Copy ux';
            app.uyEquationLabel = uilabel(app.EquationsGridLayout);
            app.uyEquationLabel.Tooltip = {'Upper surface y equation'};
            app.uyEquationLabel.Text = 'Label2';
            app.uyEquationCopyButton = uibutton(app.EquationsGridLayout, 'push');
            app.uyEquationCopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.uyEquationCopyButton.Tag = 'uy';
            app.uyEquationCopyButton.Tooltip = {'Copy equation to clipboard'};
            app.uyEquationCopyButton.Text = 'Copy uy';
            app.lxEquationLabel = uilabel(app.EquationsGridLayout);
            app.lxEquationLabel.Tooltip = {'Lower surface x equation'};
            app.lxEquationLabel.Text = 'Label3';
            app.lxEquationCopyButton = uibutton(app.EquationsGridLayout, 'push');
            app.lxEquationCopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.lxEquationCopyButton.Tag = 'lx';
            app.lxEquationCopyButton.Tooltip = {'Copy equation to clipboard'};
            app.lxEquationCopyButton.Text = 'Copy lx';
            app.lyEquationLabel = uilabel(app.EquationsGridLayout);
            app.lyEquationLabel.Tooltip = {'Lower surface y equation'};
            app.lyEquationLabel.Text = 'Label4';
            app.lyEquationCopyButton = uibutton(app.EquationsGridLayout, 'push');
            app.lyEquationCopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.lyEquationCopyButton.Tag = 'ly';
            app.lyEquationCopyButton.Tooltip = {'Copy equation to clipboard'};
            app.lyEquationCopyButton.Text = 'Copy ly';
            app.YFoilProfileOptionsPanel = uipanel(app.RootGridLayout);
            app.YFoilProfileOptionsPanel.Title = 'YFoil Profile Options';
            app.YFoilGridLayout = uigridlayout(app.YFoilProfileOptionsPanel);
            app.UpperSurfaceQualityLabel = uilabel(app.YFoilGridLayout);
            app.UpperSurfaceQualityLabel.Text = 'Upper Surface Quality:';
            app.UpperSurfaceQualitySpinner = uispinner(app.YFoilGridLayout);
            app.UpperSurfaceQualitySpinner.Step = 5e-05;
            app.UpperSurfaceQualitySpinner.Limits = [0.9 0.99999];
            app.UpperSurfaceQualitySpinner.ValueDisplayFormat = '%11.5g';
            app.UpperSurfaceQualitySpinner.ValueChangedFcn = createCallbackFcn(app, @SurfaceQualitySpinnerValueChanged, true);
            app.UpperSurfaceQualitySpinner.Tooltip = {'The minimum r² value for the yFoil approximation on the upper surface.'};
            app.UpperSurfaceQualitySpinner.Value = 0.9998;
            app.ChordParameterNameLabel = uilabel(app.YFoilGridLayout);
            app.ChordParameterNameLabel.Text = 'Chord Parameter Name:';
            app.ChordParameterNameEditField = uieditfield(app.YFoilGridLayout, 'text');
            app.ChordParameterNameEditField.CharacterLimits = [1 99];
            app.ChordParameterNameEditField.ValueChangedFcn = createCallbackFcn(app, @ChordParameterNameEditFieldValueChanged, true);
            app.ChordParameterNameEditField.Tooltip = {'The string to use when generating equations refering to the parameter referring to the chord.'; 'You may also use a static value like ''350 ul'''};
            app.ChordParameterNameEditField.Placeholder = 'chord';
            app.ChordParameterNameEditField.Value = 'chord';
            app.LowerSurfaceQualityLabel = uilabel(app.YFoilGridLayout);
            app.LowerSurfaceQualityLabel.Text = 'Lower Surface Quality:';
            app.LowerSurfaceQualitySpinner = uispinner(app.YFoilGridLayout);
            app.LowerSurfaceQualitySpinner.Step = 0.001;
            app.LowerSurfaceQualitySpinner.Limits = [0.9 0.99999];
            app.LowerSurfaceQualitySpinner.ValueDisplayFormat = '%11.5g';
            app.LowerSurfaceQualitySpinner.ValueChangedFcn = createCallbackFcn(app, @SurfaceQualitySpinnerValueChanged, true);
            app.LowerSurfaceQualitySpinner.Tooltip = {'The minimum r² value for the yFoil approximation on the lower surface.'};
            app.LowerSurfaceQualitySpinner.Value = 0.99;
            app.YFoilUpperOutputLabel = uilabel(app.YFoilGridLayout);
            app.YFoilUpperOutputLabel.Tooltip = {'''n'' is the degree of the yFoil equation. ''r²'' is the actual r² value of the yFoil approximation with respect to the reference profile.'};
            app.YFoilUpperOutputLabel.Text = 'TBC';
            app.YFoilLowerOutputLabel = uilabel(app.YFoilGridLayout);
            app.YFoilLowerOutputLabel.Tooltip = {'''n'' is the degree of the yFoil equation. ''r²'' is the actual r² value of the yFoil approximation with respect to the reference profile.'};
            app.YFoilLowerOutputLabel.Text = 'TBC';
            app.FooterGridLayout = uigridlayout(app.RootGridLayout);
            app.HelpHyperlink = uihyperlink(app.FooterGridLayout);
            app.HelpHyperlink.URL = 'https://github.com/Examath/yFoil/wiki';
            app.HelpHyperlink.Text = 'Help';
            app.PinWindowCheckBox = uicheckbox(app.FooterGridLayout);
            app.PinWindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @PinWindowCheckBoxValueChanged, true);
            app.PinWindowCheckBox.Tooltip = {'Keep this window on top of all other windows. Useful when transferring formulas.'};
            app.PinWindowCheckBox.Text = 'Pin window on top of all other windows';
            app.PerformanceLabel = uilabel(app.FooterGridLayout);
            app.PerformanceLabel.Tooltip = {'Time elapsed computing'};
            app.PerformanceLabel.Text = 'Loading ...';
            app.LogoImage = uiimage(app.RootGridLayout);
            app.LogoImage.URL = 'https://github.com/Examath';
            app.LogoImage.ImageSource = fullfile(pathToMLAPP, 'ExalabLogo.png');
            app.ReferenceProfileTabGroup = uitabgroup(app.RootGridLayout);
            app.ReferenceProfileTabGroup.SelectionChangedFcn = createCallbackFcn(app, @ReferenceProfileTabGroupSelectionChanged, true);
            app.NACAProfileTab = uitab(app.ReferenceProfileTabGroup);
            app.NACAProfileTab.Title = 'NACA Profile';
            app.NACAProfileTab.Tag = 'Naca';
            app.NacaGridLayout_2 = uigridlayout(app.NACAProfileTab);
            app.NACACodeLabel = uilabel(app.NacaGridLayout_2);
            app.NACACodeLabel.Text = 'NACA Code:';
            app.QueryField = uieditfield(app.NacaGridLayout_2, 'text');
            app.QueryField.CharacterLimits = [4 4];
            app.QueryField.InputType = 'digits';
            app.QueryField.ValueChangedFcn = createCallbackFcn(app, @QueryFieldValueChanged, true);
            app.QueryField.ValueChangingFcn = createCallbackFcn(app, @QueryFieldValueChanging, true);
            app.QueryField.Tooltip = {'Thter the 4-digit NACA specifier.'; 'The first digit is the chamber (%).'; 'The second digit is chamber position (d).'; 'The third and fourth digits are thickness (%).'};
            app.QueryField.Placeholder = 'XXXX';
            app.QueryField.Value = '2412';
            app.Lamp = uilamp(app.NacaGridLayout_2);
            app.Lamp.Tooltip = {'Green indicates that pharsing was succesful'};
            app.QualitySpinnerLabel = uilabel(app.NacaGridLayout_2);
            app.QualitySpinnerLabel.Text = 'Quality';
            app.NacaQualitySpinner = uispinner(app.NacaGridLayout_2);
            app.NacaQualitySpinner.Limits = [1 6];
            app.NacaQualitySpinner.ValueChangedFcn = createCallbackFcn(app, @NacaQualitySpinnerValueChanged, true);
            app.NacaQualitySpinner.Tooltip = {'Higher quality increases the number of generated points of the naca profile. This improves regression.'};
            app.NacaQualitySpinner.Value = 4;
            app.PointsLabel = uilabel(app.NacaGridLayout_2);
            app.PointsLabel.Tooltip = {'The number of generated NACA points (shown in magenta)'};
            app.PointsLabel.Text = '0 pts';
            app.NacaPropertiesGridLayout = uigridlayout(app.NacaGridLayout_2);
            app.ChamberSpinnerLabel = uilabel(app.NacaPropertiesGridLayout);
            app.ChamberSpinnerLabel.Text = 'Chamber';
            app.ChamberSpinner = uispinner(app.NacaPropertiesGridLayout);
            app.ChamberSpinner.Step = 0.01;
            app.ChamberSpinner.Limits = [0 0.09];
            app.ChamberSpinner.ValueDisplayFormat = '%3.2f';
            app.ChamberSpinner.ValueChangedFcn = createCallbackFcn(app, @ChamberSpinnerValueChanged, true);
            app.ChamberSpinner.Tooltip = {'The maximum chamber, or the maximum deviation of the chamber line (green) as a fraction of the chord.'};
            app.ChamberPosLabel = uilabel(app.NacaPropertiesGridLayout);
            app.ChamberPosLabel.Text = 'Chamber Pos.';
            app.ChamberPosSpinner = uispinner(app.NacaPropertiesGridLayout);
            app.ChamberPosSpinner.Step = 0.1;
            app.ChamberPosSpinner.Limits = [0 0.9];
            app.ChamberPosSpinner.ValueDisplayFormat = '%3.1f';
            app.ChamberPosSpinner.ValueChangedFcn = createCallbackFcn(app, @ChamberPosSpinnerValueChanged, true);
            app.ChamberPosSpinner.Tooltip = {'The position of the maximum chamber as a fraction of the chord from the leading edge.'};
            app.ThicknessSpinnerLabel = uilabel(app.NacaPropertiesGridLayout);
            app.ThicknessSpinnerLabel.Text = 'Thickness';
            app.ThicknessSpinner = uispinner(app.NacaPropertiesGridLayout);
            app.ThicknessSpinner.Step = 0.01;
            app.ThicknessSpinner.Limits = [0.01 0.99];
            app.ThicknessSpinner.ValueDisplayFormat = '%3.2f';
            app.ThicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @ThicknessSpinnerValueChanged, true);
            app.ThicknessSpinner.Tooltip = {'The thickness of the profile from the chamber line  as a fraction of the chord.'};
            app.ThicknessSpinner.Value = 0.01;
            app.ImportPointsTab = uitab(app.ReferenceProfileTabGroup);
            app.ImportPointsTab.Title = 'Import Points';
            app.ImportPointsTab.Tag = 'Import';
            app.GridLayout = uigridlayout(app.ImportPointsTab);
            app.ImportedPointsStatsLabel = uilabel(app.GridLayout);
            app.ImportedPointsStatsLabel.Text = 'Label5';
            app.PickFileButton = uibutton(app.GridLayout, 'push');
            app.PickFileButton.ButtonPushedFcn = createCallbackFcn(app, @PickFileButtonPushed, true);
            app.PickFileButton.Text = 'Pick File';
            % Show the figure after all components are created
            app.yFoilUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = yFoil

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.yFoilUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.yFoilUIFigure)
        end
    end
end